classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        BlackjackManMachinePanel  matlab.ui.container.Panel
        hitTextArea               matlab.ui.control.TextArea
        hitTextAreaLabel          matlab.ui.control.Label
        standTextArea             matlab.ui.control.TextArea
        standTextAreaLabel        matlab.ui.control.Label
        playTextArea              matlab.ui.control.TextArea
        playTextAreaLabel         matlab.ui.control.Label
        CurrentBetEditField       matlab.ui.control.NumericEditField
        CurrentBetEditFieldLabel  matlab.ui.control.Label
        EStopToggleButton         matlab.ui.control.StateButton
        BalanceEditField          matlab.ui.control.NumericEditField
        BalanceEditFieldLabel     matlab.ui.control.Label
        PlayButton                matlab.ui.control.Button
        HitMeButton               matlab.ui.control.Button
        StandButton               matlab.ui.control.Button
    end

    properties (Access = private)
        Game_Logic % Description
        BlackjackGame   % Instance of BlackjackTest class
        playerHand      % Player's hand (array)
        dealerHand      % Dealer's hand (array)
        currentBet      % Player's current bet
    end

    methods (Access = private)

        function updateDisplay(app)
            % Update Player Hand Text Area
            app.PlayerHandTextArea.Value = ['Player Hand: ', app.BlackjackGame.handToString(app.playerHand), ...
                ' | Total: ', num2str(app.BlackjackGame.calculateTotal(app.playerHand))];

            % Update Dealer Hand Text Area (hiding second card unless game is over)
            if strcmp(app.HitButton.Enable, 'on')  % Game is still ongoing
                app.DealerHandTextArea.Value = ['Dealer Hand: ', app.BlackjackGame.cardNames{app.dealerHand(1)}, ' and [Hidden]'];
            else  % Game is over, show all dealer cards
                app.DealerHandTextArea.Value = ['Dealer Hand: ', app.BlackjackGame.handToString(app.dealerHand), ...
                    ' | Total: ', num2str(app.BlackjackGame.calculateTotal(app.dealerHand))];
            end

            % Update Balance
            app.BalanceLabel.Text = ['Balance: $', num2str(app.BlackjackGame.balance)];

        end

        % Function to handle the end of the game (disable buttons and show message)
        function endGame(app, message)
            % Disable Hit and Stand buttons
            app.HitButton.Enable = 'off';
            app.StandButton.Enable = 'off';

            % Display end game message
            uialert(app.UIFigure, message, 'Game Over');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function PlayButtonPushed(app, event)
            % Play Button Callback
            % function PlayButtonPushed(app, event)
            % Initialize the game with the app instance
            app.Game = BlackjackTest(app);
            app.Game.play();  % Start the game

        end

        % Callback function: not associated with a component
        function HitMeButtonPushed(app, event)
            % Player hits (deals a card to the player)
            app.Game.hit();  % Implement the hit logic inside your class

        end

        % Callback function: not associated with a component
        function StandButtonPushed(app, event)
            % Player stands (moves to dealer's turn)
            app.Game.stand();  % Implement the stand logic inside your class

        end

        % Value changed function: CurrentBetEditField
        function CurrentBetEditFieldValueChanged(app, event)
            value = app.CurrentBetEditField.Value;
            % Get bet amount from a numeric field
bet = str2double(app.CurrentBetEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BlackjackManMachinePanel = uipanel(app.UIFigure);
            app.BlackjackManMachinePanel.Title = 'Blackjack: Man & Machine ';
            app.StandButton = uibutton(app.BlackjackManMachinePanel, 'push');
            app.StandButton.Text = 'Stand';
            app.HitMeButton = uibutton(app.BlackjackManMachinePanel, 'push');
            app.HitMeButton.Text = 'Hit Me';
            app.PlayButton = uibutton(app.BlackjackManMachinePanel, 'push');
            app.PlayButton.Text = 'Play';
            app.BalanceEditFieldLabel = uilabel(app.BlackjackManMachinePanel);
            app.BalanceEditFieldLabel.Text = 'Balance';
            app.BalanceEditField = uieditfield(app.BlackjackManMachinePanel, 'numeric');
            app.EStopToggleButton = uibutton(app.BlackjackManMachinePanel, 'state');
            app.EStopToggleButton.Text = 'E-Stop (Toggle)';
            app.CurrentBetEditFieldLabel = uilabel(app.BlackjackManMachinePanel);
            app.CurrentBetEditFieldLabel.Text = 'Current Bet';
            app.CurrentBetEditField = uieditfield(app.BlackjackManMachinePanel, 'numeric');
            app.CurrentBetEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentBetEditFieldValueChanged, true);
            app.playTextAreaLabel = uilabel(app.BlackjackManMachinePanel);
            app.playTextAreaLabel.Text = 'play';
            app.playTextArea = uitextarea(app.BlackjackManMachinePanel);
            app.standTextAreaLabel = uilabel(app.BlackjackManMachinePanel);
            app.standTextAreaLabel.Text = 'stand';
            app.standTextArea = uitextarea(app.BlackjackManMachinePanel);
            app.hitTextAreaLabel = uilabel(app.BlackjackManMachinePanel);
            app.hitTextAreaLabel.Text = 'hit';
            app.hitTextArea = uitextarea(app.BlackjackManMachinePanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end